classdef APP_CCDCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadcamerafileButton    matlab.ui.control.Button
        LoadFOVreferenceButton  matlab.ui.control.Button
        SendtoPreProcButton     matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        EditField_2             matlab.ui.control.EditField
        LoadROIPositionButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        camera
        fig
        camera_new
        fig_2
      % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcamerafileButton
        function LoadcamerafileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            
            a = load([path file]);
            if isfield(a,'cmos')
                app.camera = a.cmos;
                app.camera_new = app.camera;
                app.LoadFOVreferenceButton.Enable = 0;
                app.UIAxes2.Visible = 0;
            else
            app.camera = a.camera;
            app.camera = squeeze(app.camera(:,:,1,2)-app.camera(:,:,1,1));
            app.LoadFOVreferenceButton.Enable = 1;
            end
            app.fig = imagesc(app.UIAxes,app.camera); axis(app.UIAxes,'image');
        end

        % Button pushed function: SendtoPreProcButton
        function SendtoPreProcButtonPushed(app, event)
            assignin('base', 'camera', app.camera_new);
            app.delete;
        end

        % Button pushed function: LoadFOVreferenceButton
        function LoadFOVreferenceButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_2.Value = [path file];
            
            a = load([path file]);
            app.camera_new = fliplr(imwarp(app.camera,a.tform,'OutputView',imref2d(a.pixels)));
            app.fig_2 = imagesc(app.UIAxes2,app.camera_new); axis(app.UIAxes2,'image');
            
            app.LoadROIPositionButton.Enable = 1;
        end

        % Button pushed function: LoadROIPositionButton
        function LoadROIPositionButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_2.Value = [path file];
            
            a = load([path file]);
            custom_ROI = round(a.custom_ROI);
            app.camera_new = app.camera_new((custom_ROI(2):(custom_ROI(2)+custom_ROI(4))),(custom_ROI(1):(custom_ROI(1)+custom_ROI(3))));
            app.fig_2.CData = app.camera_new;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadcamerafileButton = uibutton(app.UIFigure, 'push');
            app.LoadcamerafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcamerafileButtonPushed, true);
            app.LoadcamerafileButton.Text = 'Load camera file';
            app.LoadFOVreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadFOVreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFOVreferenceButtonPushed, true);
            app.LoadFOVreferenceButton.Text = 'Load FOV reference';
            app.SendtoPreProcButton = uibutton(app.UIFigure, 'push');
            app.SendtoPreProcButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoPreProcButtonPushed, true);
            app.SendtoPreProcButton.Text = 'Send to PreProc';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.LoadROIPositionButton = uibutton(app.UIFigure, 'push');
            app.LoadROIPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIPositionButtonPushed, true);
            app.LoadROIPositionButton.Enable = 'off';
            app.LoadROIPositionButton.Text = 'Load ROI Position';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CCDCalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end