classdef APP_ImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        FlipCheckBox                    matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        NegEditFieldLabel               matlab.ui.control.Label
        NegEditField                    matlab.ui.control.NumericEditField
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        PatterntypeDropDown_2Label_2    matlab.ui.control.Label
        CWDropDown                      matlab.ui.control.DropDown
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        SinglePixelCameraImageReconstructionLabel  matlab.ui.control.Label
        BasisDropDownLabel              matlab.ui.control.Label
        BasisDropDown                   matlab.ui.control.DropDown
        LoadfileButton                  matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        PatterntypeDropDown_2Label      matlab.ui.control.Label
        PatterntypeDropDown_2           matlab.ui.control.DropDown
        CompressivesensingSwitchLabel   matlab.ui.control.Label
        CompressivesensingSwitch        matlab.ui.control.Switch
        RatioEditFieldLabel             matlab.ui.control.Label
        RatioEditField                  matlab.ui.control.NumericEditField
        CompressionTypeDropDownLabel    matlab.ui.control.Label
        CompressionTypeDropDown         matlab.ui.control.DropDown
        ReconstructButton               matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        ColormapLimEditFieldLabel       matlab.ui.control.Label
        ColormapLimEditField            matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        IntegralovertimeCheckBox        matlab.ui.control.CheckBox
        IntegraloverwavelengthCheckBox  matlab.ui.control.CheckBox
        ReleasefileButton               matlab.ui.control.Button
        FuseSPCandCameraButton          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        spc
        t
        lambda
        im
        loaded
        camera
        M
        had_spectrum
        had
        im_fused
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.spc = evalin('base','spc');
                app.t = evalin('base','t');
                app.EditField.Value = 'Loaded file from workspace.';
                plot(app.UIAxes,squeeze(sum(app.spc,1:2)));
                xlim(app.UIAxes, [1 size(app.spc,3)])
                grid(app.UIAxes,"minor")
                box(app.UIAxes,"on")
            catch
                return;
            end
            
            try
                app.lambda = evalin('base','lambda');
            catch
                return;
            end
            
            try
                app.camera = evalin('base','camera');
            catch
                return;
            end
        end

        % Value changed function: LoadfileButton
        function LoadfileButtonValueChanged(app, event)
            addpath './common/'
            [file, path] = uigetfile('*.mat');
            app.LoadfileButton.Value = 0;
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            a = load([path file]);
            app.LoadfileButton.Value = 0;
            app.spc = a.spc;
            if isfield(a,'lambda') ~= 0
                app.lambda = a.lambda;
            else
                app.lambda = [];
            end
            app.t = a.t;
            app.camera = a.camera;
            app.had_spectrum = plot(app.UIAxes,squeeze(sum(app.spc,1:2)));
            xlim(app.UIAxes, [1 size(app.spc,3)])
            grid(app.UIAxes,"minor")
            box(app.UIAxes,"on")
            app.LoadfileButton.Enable = 0;
        end

        % Value changed function: ReconstructButton
        function ReconstructButtonValueChanged(app, event)
            spc_raw = app.spc;
            
            if app.IntegralovertimeCheckBox.Value == 1
                app.spc = sum(app.spc,1);
            end
            
            if app.IntegraloverwavelengthCheckBox.Value == 1
                app.spc = sum(app.spc,2);
            end
            
            if not(strcmp(app.BasisDropDown.Value,'Raster Scan'))
            switch app.PatterntypeDropDown_2.Value
                case 'Pos-Neg'
                    app.spc = CWHandling(app.spc,app.CWDropDown.Value,'Pos-Neg');
                    had = PosNeg2Had(app.spc);
                case 'Shifted Simulation'
                    app.spc = CWHandling(app.spc,app.CWDropDown.Value,'Pos-Neg'); %for CW handl. Pos simulation implies a pos-neg meas.
                    had = PosNeg2Pos(app.spc,app.NegEditField.Value,app.MethodDropDown.Value);
                case 'Shifted'
                    K = sqrt(2^floor(log2(size(app.spc,3)-app.NegEditField.Value)));
                    had = Shifted2Had(app.spc(:,:,1:(K^2+app.NegEditField.Value)),K,app.NegEditField.Value,app.MethodDropDown.Value,app.CWDropDown.Value);
            end
            else
                had = [];
            end
            app.had_spectrum.YData = squeeze(sum(app.spc,1:2));
            
            if strcmp(app.CompressivesensingSwitch.Value,'On')
                had = PutCofficients2Zero(had,app.RatioEditField.Value);    
            end
            
            app.had = had;
            if size(app.loaded,1) == 0 && strcmp(app.BasisDropDown.Value,'Scrambled-Hadamard')
                try
                    load(['FLIM_',app.BasisDropDown.Value,'_',num2str(size(had,3))],'M','Pc','Pr')
                catch
                    [file, path] = uigetfile(['FLIM_',app.BasisDropDown.Value,'_',num2str(size(had,3))]);
                    figure(app.UIFigure)
                    if file == 0
                        return;
                    end
                    load([path file],'M','Pc','Pr');
                    app.loaded = 1;
                    app.M = M;
                end
            elseif size(app.loaded,1) == 1 && strcmp(app.BasisDropDown.Value,'Scrambled-Hadamard')
                    M = app.M;
            end
            flag_flip = app.FlipCheckBox.Value;
            switch app.BasisDropDown.Value
                case 'Walsh-Hadamard'
                    im = WHReconstruction(had,flag_flip);
                case 'Scrambled-Hadamard'
                    im = SHReconstruction(had,M,flag_flip);
                case 'Raster Scan'
                    im = reshape(app.spc(:,:,1:app.XEditField_2.Value*app.XEditField.Value),[size(app.spc,1) size(app.spc,2) app.XEditField_2.Value app.XEditField.Value]);
            end
            app.im = im;
            imagesc(app.UIAxes2,squeeze(sum(app.im,1:2)));
            axis(app.UIAxes2,"image")
            c = colorbar(app.UIAxes2);
            app.ReconstructButton.Value = 0;
            app.ColormapLimEditField.Value = round(app.UIAxes2.CLim(1));
            app.EditField_2.Value = round(app.UIAxes2.CLim(2));
            app.ColormapLimEditField.Visible = 'on';
            app.ColormapLimEditFieldLabel.Visible = 'on';
            app.EditField_2.Visible = 'on';
            app.spc = spc_raw;
            
            if sum(app.camera(:))>0 && size(app.im,1)>1 && size(app.im,2)>1
            app.FuseSPCandCameraButton.Enable = 1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Enable = 0;
            app.ReconstructButton.Enable = 0;
            app.ReleasefileButton.Enable = 0;
            im = app.im;
            t = app.t;
            lambda = app.lambda;
            camera = app.camera;
            had = app.had;
            im_fused = app.im_fused;
            assignin('base', 'lambda', lambda);
            assignin('base', 't', t);
            assignin('base', 'im', im);
            assignin('base', 'camera', camera);
            assignin('base', 'had', had);
            assignin('base', 'im_fused', im_fused);
            if not(strcmp(app.EditField.Value,'Loaded file from workspace.'))
            filename = app.EditField.Value;
            filename = filename(1:end-4);
            else
                filename = [];
            end
            %save([filename,'_tlxy.mat'],"im");
            uisave(["im","t","lambda","camera","had","im_fused"],[filename,'_tlxy.mat'])
            app.SaveButton.Enable = 1;
            app.ReconstructButton.Enable = 1;
            app.ReleasefileButton.Enable = 1;
        end

        % Value changed function: PatterntypeDropDown_2
        function PatterntypeDropDown_2ValueChanged(app, event)
            if strcmp(app.PatterntypeDropDown_2.Value,'Shifted Simulation')
                app.NegEditField.Visible = 'on';
                app.NegEditFieldLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                app.MethodDropDownLabel.Visible = 'on';
            elseif strcmp(app.PatterntypeDropDown_2.Value,'Shifted')
                app.NegEditField.Visible = 'on';
                app.NegEditFieldLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                app.MethodDropDownLabel.Visible = 'on';
            else
                app.NegEditField.Visible = 'off';
                app.NegEditFieldLabel.Visible = 'off';
                app.MethodDropDown.Visible = 'off';
                app.MethodDropDownLabel.Visible = 'off';
            end
        end

        % Value changed function: CompressivesensingSwitch
        function CompressivesensingSwitchValueChanged(app, event)
            value = app.CompressivesensingSwitch.Value;
            if strcmp(value,'On')
                app.CompressionTypeDropDown.Enable = 'on';
                app.RatioEditField.Enable = 'on';
                app.CompressionTypeDropDownLabel.Enable = 'on';
                app.RatioEditFieldLabel.Enable = 'on';
            else
                app.CompressionTypeDropDown.Enable = 'off';
                app.RatioEditField.Enable = 'off';
                app.CompressionTypeDropDownLabel.Enable = 'off';
                app.RatioEditFieldLabel.Enable = 'off';
                app.RatioEditField.Value = 0;
            end
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.UIAxes2.CLim(2) = app.EditField_2.Value;
        end

        % Value changed function: ColormapLimEditField
        function ColormapLimEditFieldValueChanged(app, event)
            app.UIAxes2.CLim(1) = app.ColormapLimEditField.Value;
            
        end

        % Callback function
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ReleasefileButton
        function ReleasefileButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            im = [];
            t = [];
            lambda = [];
            camera = [];
            im_fused = [];
            assignin('base', 'lambda', lambda);
            assignin('base', 't', t);
            assignin('base', 'im', im);
            assignin('base', 'camera', camera);
            assignin('base', 'im_fused', im_fused);
            APP_ImageReconstruction();  
            % Delete old instance
            close(app.UIFigure)
        end

        % Value changed function: BasisDropDown
        function BasisDropDownValueChanged(app, event)
            if strcmp(app.BasisDropDown.Value,'Raster Scan')
                app.CWDropDown.Visible = 0;
                app.PatterntypeDropDown_2.Visible = 0;
                app.XEditField.Visible = 1;
                app.XEditField_2.Visible = 1;
                app.XEditFieldLabel.Visible = 1;
                app.XEditFieldLabel_2.Visible = 1;
            else
                app.CWDropDown.Visible = 1;
                app.PatterntypeDropDown_2.Visible = 1;
                app.XEditField.Visible = 0;
                app.XEditField_2.Visible = 0;
                app.XEditFieldLabel.Visible = 0;
                app.XEditFieldLabel_2.Visible = 0;
            end
            
        end

        % Button pushed function: FuseSPCandCameraButton
        function FuseSPCandCameraButtonPushed(app, event)
            addpath './data_fusion'
            addpath './data_fusion/routines'
            val_spc = max(max(sum(app.im,1:2)));
            val_ccd = max(max(imresize(app.camera,[size(app.im,3) size(app.im,4)])));
            im = permute(app.im,[3 4 2 1]);
            Data.CCD = imresize(app.camera,[128 128]).*val_spc/val_ccd;
            Data.L16 = squeeze(sum(im,4));
            Data.PMT = squeeze(sum(im,3));
            Data.time = app.t;
            Data.lambda = app.lambda;
            
            app.im_fused = data_fusion(Data);
            imagesc(app.UIAxes2,squeeze(sum(app.im_fused,1:2)));
            app.ColormapLimEditField.Value = round(app.UIAxes2.CLim(1));
            app.EditField_2.Value = round(app.UIAxes2.CLim(2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.FlipCheckBox = uicheckbox(app.Panel_2);
            app.FlipCheckBox.Text = 'Flip';
            app.FlipCheckBox.Value = true;
            app.Panel = uipanel(app.UIFigure);
            app.NegEditFieldLabel = uilabel(app.Panel);
            app.NegEditFieldLabel.Visible = 'off';
            app.NegEditFieldLabel.Text = '#Neg';
            app.NegEditField = uieditfield(app.Panel, 'numeric');
            app.NegEditField.Visible = 'off';
            app.NegEditField.Value = 40;
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Mean', 'Distributed'};
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'Mean';
            app.PatterntypeDropDown_2Label_2 = uilabel(app.Panel);
            app.PatterntypeDropDown_2Label_2.Text = 'CW type';
            app.CWDropDown = uidropdown(app.Panel);
            app.CWDropDown.Items = {'Measured', 'Not measured', 'Measured, but exclude'};
            app.CWDropDown.Value = 'Measured';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.XEditField.Visible = 'off';
            app.XEditField.Value = 32;
            app.XEditFieldLabel_2 = uilabel(app.Panel);
            app.XEditFieldLabel_2.Visible = 'off';
            app.XEditFieldLabel_2.Text = 'Y';
            app.XEditField_2 = uieditfield(app.Panel, 'numeric');
            app.XEditField_2.Visible = 'off';
            app.XEditField_2.Value = 32;
            app.SinglePixelCameraImageReconstructionLabel = uilabel(app.UIFigure);
            app.SinglePixelCameraImageReconstructionLabel.Text = 'Single Pixel Camera Image Reconstruction';
            app.BasisDropDownLabel = uilabel(app.UIFigure);
            app.BasisDropDownLabel.Text = 'Basis';
            app.BasisDropDown = uidropdown(app.UIFigure);
            app.BasisDropDown.Items = {'Walsh-Hadamard', 'Scrambled-Hadamard', 'Raster Scan'};
            app.BasisDropDown.ValueChangedFcn = createCallbackFcn(app, @BasisDropDownValueChanged, true);
            app.BasisDropDown.Value = 'Walsh-Hadamard';
            app.LoadfileButton = uibutton(app.UIFigure, 'state');
            app.LoadfileButton.ValueChangedFcn = createCallbackFcn(app, @LoadfileButtonValueChanged, true);
            app.LoadfileButton.Text = 'Load file';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'file.mat';
            app.PatterntypeDropDown_2Label = uilabel(app.UIFigure);
            app.PatterntypeDropDown_2Label.Text = 'Pattern type';
            app.PatterntypeDropDown_2 = uidropdown(app.UIFigure);
            app.PatterntypeDropDown_2.Items = {'Pos-Neg', 'Shifted Simulation', 'Shifted'};
            app.PatterntypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PatterntypeDropDown_2ValueChanged, true);
            app.PatterntypeDropDown_2.Value = 'Pos-Neg';
            app.CompressivesensingSwitchLabel = uilabel(app.UIFigure);
            app.CompressivesensingSwitchLabel.Text = 'Compressive sensing';
            app.CompressivesensingSwitch = uiswitch(app.UIFigure, 'slider');
            app.CompressivesensingSwitch.ValueChangedFcn = createCallbackFcn(app, @CompressivesensingSwitchValueChanged, true);
            app.RatioEditFieldLabel = uilabel(app.UIFigure);
            app.RatioEditFieldLabel.Enable = 'off';
            app.RatioEditFieldLabel.Text = 'Ratio [%]';
            app.RatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatioEditField.Enable = 'off';
            app.CompressionTypeDropDownLabel = uilabel(app.UIFigure);
            app.CompressionTypeDropDownLabel.Enable = 'off';
            app.CompressionTypeDropDownLabel.Text = 'Compression Type';
            app.CompressionTypeDropDown = uidropdown(app.UIFigure);
            app.CompressionTypeDropDown.Items = {'Algebric Inversion'};
            app.CompressionTypeDropDown.Enable = 'off';
            app.CompressionTypeDropDown.Value = 'Algebric Inversion';
            app.ReconstructButton = uibutton(app.UIFigure, 'state');
            app.ReconstructButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructButtonValueChanged, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ColormapLimEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapLimEditFieldLabel.Visible = 'off';
            app.ColormapLimEditFieldLabel.Text = 'ColormapLim';
            app.ColormapLimEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColormapLimEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapLimEditFieldValueChanged, true);
            app.ColormapLimEditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.IntegralovertimeCheckBox = uicheckbox(app.UIFigure);
            app.IntegralovertimeCheckBox.Text = 'Integral over time';
            app.IntegralovertimeCheckBox.Value = true;
            app.IntegraloverwavelengthCheckBox = uicheckbox(app.UIFigure);
            app.IntegraloverwavelengthCheckBox.Text = 'Integral over wavelength';
            app.IntegraloverwavelengthCheckBox.Value = true;
            app.ReleasefileButton = uibutton(app.UIFigure, 'push');
            app.ReleasefileButton.ButtonPushedFcn = createCallbackFcn(app, @ReleasefileButtonPushed, true);
            app.ReleasefileButton.Text = 'Release file';
            app.FuseSPCandCameraButton = uibutton(app.UIFigure, 'push');
            app.FuseSPCandCameraButton.ButtonPushedFcn = createCallbackFcn(app, @FuseSPCandCameraButtonPushed, true);
            app.FuseSPCandCameraButton.Enable = 'off';
            app.FuseSPCandCameraButton.Text = 'Fuse SPC and Camera';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired spectrum')
            xlabel(app.UIAxes, 'Patterns')
            ylabel(app.UIAxes, 'Counts [a.u.]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_ImageReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end