classdef APP_CCDFOVPattern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadcamerafileButton         matlab.ui.control.Button
        LoadFOVreferenceButton       matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        DrawaROIButton               matlab.ui.control.Button
        SavepatternButton            matlab.ui.control.Button
        LoadPatternusedforCCDButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        camera
        fig
        camera_new
        fig_2
        ref_pattern
        ImageROI
        fig_3
        pos
        ROI
        target_pattern
      % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadcamerafileButton
        function LoadcamerafileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField.Value = [path file];
            
            a = load([path file]);
            if isfield(a,'cmos')
                app.camera = a.cmos;
                app.camera_new = app.camera;
                app.LoadFOVreferenceButton.Enable = 0;
                app.UIAxes2.Visible = 0;
            else
            app.camera = a.camera;
            app.camera = squeeze(app.camera(:,:,1,2)-app.camera(:,:,1,1));
            app.LoadFOVreferenceButton.Enable = 1;
            end
            app.fig = imagesc(app.UIAxes,app.camera); axis(app.UIAxes,'image');
        end

        % Button pushed function: LoadFOVreferenceButton
        function LoadFOVreferenceButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            figure(app.UIFigure)
            if file == 0
                return;
            end
            app.EditField_2.Value = [path file];
            
            a = load([path file]);
            app.camera_new = fliplr(imwarp(app.camera,a.tform,'OutputView',imref2d(a.pixels)));
            app.fig_2 = imagesc(app.UIAxes2,app.camera_new); axis(app.UIAxes2,'image');
        end

        % Button pushed function: LoadPatternusedforCCDButton
        function LoadPatternusedforCCDButtonPushed(app, event)
            [file, path] = uigetfile;
            app.ref_pattern = imread([path file]);
        end

        % Button pushed function: SavepatternButton
        function SavepatternButtonPushed(app, event)
            imwrite(app.target_pattern,"TargetPattern.png")
        end

        % Button pushed function: DrawaROIButton
        function DrawaROIButtonPushed(app, event)
            if size(app.ImageROI,1) > 0
                delete(app.ImageROI)
            end
            app.ImageROI = drawpolygon(app.UIAxes2);
            bw = poly2mask(round(app.ImageROI.Position(:,1)),round(app.ImageROI.Position(:,2)),size(app.camera_new,1),size(app.camera_new,2));
                        
            [a,~] = find(app.ref_pattern(:)>0);
            [row,col] = ind2sub(size(app.ref_pattern),a(1)); 
            size_pattern = sqrt(sum(app.ref_pattern(:)./255));
            app.ROI = 255.*uint8(imresize(bw,[size_pattern size_pattern],'box'));
            app.fig_3 = imagesc(app.UIAxes3,bw); axis(app.UIAxes3,'image'); colormap(app.UIAxes3,'gray');
            
            
            % Pattern
            app.target_pattern = uint8(zeros(size(app.ref_pattern)));
            app.target_pattern(row:(row+size_pattern-1),col:(col+size_pattern-1)) = app.ROI;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadcamerafileButton = uibutton(app.UIFigure, 'push');
            app.LoadcamerafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcamerafileButtonPushed, true);
            app.LoadcamerafileButton.Text = 'Load camera file';
            app.LoadFOVreferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadFOVreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFOVreferenceButtonPushed, true);
            app.LoadFOVreferenceButton.Text = 'Load FOV reference';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.DrawaROIButton = uibutton(app.UIFigure, 'push');
            app.DrawaROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawaROIButtonPushed, true);
            app.DrawaROIButton.Text = 'Draw a ROI';
            app.SavepatternButton = uibutton(app.UIFigure, 'push');
            app.SavepatternButton.ButtonPushedFcn = createCallbackFcn(app, @SavepatternButtonPushed, true);
            app.SavepatternButton.Text = 'Save pattern';
            app.LoadPatternusedforCCDButton = uibutton(app.UIFigure, 'push');
            app.LoadPatternusedforCCDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatternusedforCCDButtonPushed, true);
            app.LoadPatternusedforCCDButton.Text = 'Load Pattern used for CCD';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pattern')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_CCDFOVPattern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end